classdef stack_viewer_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                  matlab.ui.Figure
        z_slider                 matlab.ui.control.Slider
        ch1_disp                 matlab.ui.control.CheckBox
        ch2_disp                 matlab.ui.control.CheckBox
        ch3_disp                 matlab.ui.control.CheckBox
        title                    matlab.ui.control.Label
        ch1_min                  matlab.ui.control.EditField
        ch1_max                  matlab.ui.control.EditField
        ch2_min                  matlab.ui.control.EditField
        ch2_max                  matlab.ui.control.EditField
        ch3_min                  matlab.ui.control.EditField
        ch3_max                  matlab.ui.control.EditField
        text3                    matlab.ui.control.Label
        text4                    matlab.ui.control.Label
        text5                    matlab.ui.control.Label
        well_select              matlab.ui.control.EditField
        text6                    matlab.ui.control.Label
        text7                    matlab.ui.control.Label
        pos_select               matlab.ui.control.EditField
        auto_contrast            matlab.ui.control.Button
        ch4_disp                 matlab.ui.control.CheckBox
        ch4_min                  matlab.ui.control.EditField
        ch4_max                  matlab.ui.control.EditField
        ch5_disp                 matlab.ui.control.CheckBox
        ch5_min                  matlab.ui.control.EditField
        ch5_max                  matlab.ui.control.EditField
        cellseg                  matlab.ui.control.CheckBox
        arrayseg                 matlab.ui.control.CheckBox
        x_min                    matlab.ui.control.EditField
        x_max                    matlab.ui.control.EditField
        y_min                    matlab.ui.control.EditField
        y_max                    matlab.ui.control.EditField
        text8                    matlab.ui.control.Label
        text9                    matlab.ui.control.Label
        text10                   matlab.ui.control.Label
        text11                   matlab.ui.control.Label
        ResetZoomButton          matlab.ui.control.Button
        DownButton               matlab.ui.control.Button
        UpButton                 matlab.ui.control.Button
        ZoomModeButton           matlab.ui.control.StateButton
        WidthEditFieldLabel      matlab.ui.control.Label
        WidthEditField           matlab.ui.control.EditField
        SaveImagesButton         matlab.ui.control.Button
        text4_2                  matlab.ui.control.Label
        text4_3                  matlab.ui.control.Label
        ch1_show_arrayseg        matlab.ui.control.CheckBox
        ch1_show_cellseg         matlab.ui.control.CheckBox
        ch2_show_arrayseg        matlab.ui.control.CheckBox
        ch2_show_cellseg         matlab.ui.control.CheckBox
        ch3_show_arrayseg        matlab.ui.control.CheckBox
        ch3_show_cellseg         matlab.ui.control.CheckBox
        ch4_show_arrayseg        matlab.ui.control.CheckBox
        ch4_show_cellseg         matlab.ui.control.CheckBox
        ch5_show_arrayseg        matlab.ui.control.CheckBox
        ch5_show_cellseg         matlab.ui.control.CheckBox
        ImageNameEditFieldLabel  matlab.ui.control.Label
        image_name               matlab.ui.control.EditField
        LineWidthEditFieldLabel  matlab.ui.control.Label
        linewidth                matlab.ui.control.EditField
        prev_im                  matlab.ui.control.Button
        next_im                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        current_im %image data to display
        seg %segmentation data for current image
        reload %whether it's necessary to reload the figure
        out_prefix % prefix for results folder to load from
        imdir % location of image files to load
        p % parameters used for segmentation and analysis
        X % width of the image in pixels
        Y % height of the image in pixels
        colors % color for eac imaging channel to display as
        fnames % filenames of all image files in order
        fidx %index in fnames of the current file
    end
    
    methods (Access = private)
        function app = refreshfig(app)
            colors = app.colors;
            
            z = get(app.z_slider,'Value');
            well = get(app.well_select,'Value');
            pos = get(app.pos_select,'Value');
            
            if app.reload
            
                if contains(pos,'-')
                    fname = [well '-' pos '.mat'];
                else
                    fname = [well '_' pos '.mat'];
                end
            
                load([app.imdir fname],'current_im')
                load([app.imdir '../' app.out_prefix 'segmentations/' fname],'seg')
                app.current_im = current_im;
                app.seg = seg;
                app.reload = false;
            end
            
            imrgb = zeros(app.Y,app.X,3);
            
            for ch = 1:length(app.p.channels)
                if ~get(app.(['ch' num2str(ch) '_disp']),'Value')
                    continue
                end
            
                ch_im = app.current_im(ch).im(:,:,z);
            
                low = str2double(get(app.(['ch' num2str(ch) '_min']),'Value'));
                high = str2double(get(app.(['ch' num2str(ch) '_max']),'Value'));
            
                ch_im = imadjust(ch_im./high,[low./high;1],[0;1]);
                imrgb = imrgb + cat(3, ch_im*colors(ch,1),...
                    ch_im*colors(ch,2),ch_im*colors(ch,3));
            end
            
            figure(1)
            imshow(imrgb,'Parent',gca)
            hold on
            
            if get(app.cellseg,'Value')
                if get(app.arrayseg,'Value')
                    style = '--';
                else
                    style = '-';
                end
            
                B = bwboundaries(app.seg.cells(:,:,z));
                for b = 1:length(B)
                    bdry = B{b};
                    plot(gca,bdry(:,2), bdry(:,1), 'Color','white',...
                        'LineWidth',1,'LineStyle',style);
                end
            end
            
            if get(app.arrayseg,'Value')
                B = bwboundaries(app.seg.arrays(:,:,z));
                for b = 1:length(B)
                    bdry = B{b};
                    plot(gca,bdry(:,2), bdry(:,1), 'Color', 'white',...
                        'LineWidth',1,'LineStyle','-');
                end
            end
            
            hold off
            
            xlim(gca,[str2double(get(app.x_min,'Value')),...
                str2double(get(app.x_max,'Value'))])
            ylim(gca,[str2double(get(app.y_min,'Value')),...
                str2double(get(app.y_max,'Value'))])
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function stack_viewer_OpeningFcn(app)
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            % varargin   unrecognized PropertyName/PropertyValue pairs from the
            %            command line (see VARARGIN)
            
            [imfile, imdir] = uigetfile('*.mat', 'Point to image file...');
            out_folder = split(imdir,{'/','\'});
            out_folder = out_folder{end-1};
            out_prefix = split(out_folder,'_');
            if length(out_prefix) > 2
                out_prefix = join(out_prefix(1:end-2),'_');
                out_prefix = [out_prefix{1} '_'];
            else
                out_prefix = '';
            end
            app.out_prefix = out_prefix;
            
            load([imdir imfile],'current_im')
            load([imdir '../' out_prefix 'segmentations/' imfile],'seg')
            load([imdir '../' out_prefix 'params.mat'],'p')
            
            if ~isfield(p,'min_quantile')
                p.min_quantile = 0.8;
            end
            if ~isfield(p,'max_quantile')
                p.max_quantile = 0.999;
            end
            
            app.current_im = current_im;
            app.seg = seg;
            app.imdir = imdir;
            app.fnames = {dir([imdir '*.mat']).name};
            app.fidx = find(strcmp(imfile,app.fnames));
            app.p = p;
            app.X = size(current_im(1).im,2);
            app.Y = size(current_im(1).im,1);
            app.colors = [
                1,1,1;
                1,0,1;
                0,1,0;
                1,0,0;
                0,1,1;
                ];
            
            %set initial vlaues for all gui elements
            set(app.well_select,'Value',imfile(1:2))
            set(app.pos_select,'Value',imfile(4:end-4))
            %set(app.pos_select,'Value',1)
            
            
            set(app.z_slider,'Limits', [min(p.slices), max(p.slices)]);
            set(app.z_slider,'MajorTicks', p.slices);
            set(app.z_slider,'Value', 1);
            
            set(app.ch2_show_cellseg,'Value',true)
            set(app.ch3_show_arrayseg,'Value',true)
            set(app.ch4_show_arrayseg,'Value',true)
            set(app.ch4_show_cellseg,'Value',true)
            set(app.ch5_show_arrayseg,'Value',true)
            set(app.ch5_show_cellseg,'Value',true)
            
            set(app.image_name,'Value','image')
            set(app.linewidth,'Value','3')
            
            app.reload = false;
            
            for ch = 1:length(p.channels)
                set(app.(['ch' num2str(ch) '_disp']),'Value',false)
            
                im = current_im(ch).im(:,:,1);
                if ch == 1
                    ch_min = quantile(im(:),0.01);
                    ch_max = quantile(im(:),0.999);
                else
                     ch_min = quantile(im(:),p.min_quantile);
                     ch_max = quantile(im(:),p.max_quantile);
                end
                set(app.(['ch' num2str(ch) '_min']),'Value',num2str(ch_min))
                set(app.(['ch' num2str(ch) '_max']),'Value',num2str(ch_max))
            end
            
            set(app.x_min,'Value','0')
            set(app.x_max,'Value','1080')
            set(app.y_min,'Value','0')
            set(app.y_max,'Value','1080')
            
            set(app.WidthEditField,'Value','100')
            
            figure(1)
            imshow(zeros(app.Y,app.X,3),'Parent',gca)
            
            % Choose default command line output for stack_viewer
            %app.output = hObject;
            
            % Update app structure
            %%guidata(hObject, app);
            
            % UIWAIT makes stack_viewer wait for user response (see UIRESUME)
            % uiwait(app.figure1);
        end

        % Value changed function: arrayseg
        function arrayseg_Callback(app, event)
            % hObject    handle to arrayseg (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of arrayseg
            % if get(app.arrayseg,'Value')
            %     set(app.cellseg,'Value',false)
            % end
            app = refreshfig(app);
            %guidata(hObject, app);
        end

        % Button pushed function: auto_contrast
        function auto_contrast_Callback(app, event)
            % hObject    handle to auto_contrast (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            p = app.p;
            
            z = get(app.z_slider,'Value');
            for ch = 1:length(p.channels)
                if ~get(app.(['ch' num2str(ch) '_disp']),'Value')
                    continue
                end
            
                im = app.current_im(ch).im(:,:,z);
                if ch == 1
                    low = quantile(im(:),0.01);
                    high = quantile(im(:),0.999);
                else
                    high = quantile(im(:),p.max_quantile);
                    low = quantile(im(:),p.min_quantile);
                end
                set(app.(['ch' num2str(ch) '_min']),'Value',num2str(low))
                set(app.(['ch' num2str(ch) '_max']),'Value',num2str(high))
            end
            
            app = refreshfig(app);
            %guidata(hObject, app);
        end

        % Value changed function: cellseg
        function cellseg_Callback(app, event)
            % hObject    handle to cellseg (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of cellseg
            % if get(app.cellseg,'Value')
            %     set(app.arrayseg,'Value',false)
            % end
            app = refreshfig(app);
            %guidata(hObject, app);
        end

        % Value changed function: ch1_disp
        function ch1_disp_Callback(app, event)
            % hObject    handle to ch1_disp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of ch1_disp
            app = refreshfig(app);
            %guidata(hObject, app);
        end

        % Value changed function: ch1_max
        function ch1_max_Callback(app, event)
            % hObject    handle to ch1_max (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ch1_max as text
            %        str2double(get(hObject,'String')) returns contents of ch1_max as a double
            %set(app.ch1_max,'Value',str2num(get(hObject,'String')))
            app = refreshfig(app);
            %guidata(hObject, app);
        end

        % Value changed function: ch1_min
        function ch1_min_Callback(app, event)
            % hObject    handle to ch1_min (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ch1_min as text
            %        str2double(get(hObject,'String')) returns contents of ch1_min as a double
            %set(app.ch1_min,'Value',str2num(get(hObject,'String')))
            app = refreshfig(app);
            %guidata(hObject, app);
        end

        % Value changed function: ch2_disp
        function ch2_disp_Callback(app, event)
            % hObject    handle to ch2_disp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of ch2_disp
            app = refreshfig(app);
            %guidata(hObject, app);
        end

        % Value changed function: ch2_max
        function ch2_max_Callback(app, event)
            % hObject    handle to ch2_max (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ch2_max as text
            %        str2double(get(hObject,'String')) returns contents of ch2_max as a double
            %set(app.ch2_max,'Value',str2num(get(hObject,'String')))
            app = refreshfig(app);
            %guidata(hObject, app);
        end

        % Value changed function: ch2_min
        function ch2_min_Callback(app, event)
            % hObject    handle to ch2_min (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ch2_min as text
            %        str2double(get(hObject,'String')) returns contents of ch2_min as a double
            %set(app.ch2_min,'Value',str2num(get(hObject,'String')))
            app = refreshfig(app);
            %guidata(hObject, app);
        end

        % Value changed function: ch3_disp
        function ch3_disp_Callback(app, event)
            % hObject    handle to ch3_disp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of ch3_disp
            app = refreshfig(app);
            %guidata(hObject, app);
        end

        % Value changed function: ch3_max
        function ch3_max_Callback(app, event)
            % hObject    handle to ch3_max (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ch3_max as text
            %        str2double(get(hObject,'String')) returns contents of ch3_max as a double
            %set(app.ch3_max,'Value',str2num(get(hObject,'String')))
            app = refreshfig(app);
            %guidata(hObject, app);
        end

        % Value changed function: ch3_min
        function ch3_min_Callback(app, event)
            % hObject    handle to ch3_min (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ch3_min as text
            %        str2double(get(hObject,'String')) returns contents of ch3_min as a double
            %set(app.ch3_min,'Value',str2num(get(hObject,'String')))
            app = refreshfig(app);
            %guidata(hObject, app);
        end

        % Value changed function: ch4_disp
        function ch4_disp_Callback(app, event)
            % hObject    handle to ch4_disp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of ch4_disp
            app = refreshfig(app);
            %guidata(hObject, app);
        end

        % Value changed function: ch4_max
        function ch4_max_Callback(app, event)
            % hObject    handle to ch4_max (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ch4_max as text
            %        str2double(get(hObject,'String')) returns contents of ch4_max as a double
            %set(app.ch4_max,'Value',str2num(get(hObject,'String')))
            app = refreshfig(app);
            %guidata(hObject, app);
        end

        % Value changed function: ch4_min
        function ch4_min_Callback(app, event)
            % hObject    handle to ch4_min (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ch4_min as text
            %        str2double(get(hObject,'String')) returns contents of ch4_min as a double
            %set(app.ch4_min,'Value',str2num(get(hObject,'String')))
            app = refreshfig(app);
            %guidata(hObject, app);
        end

        % Value changed function: ch5_disp
        function ch5_disp_Callback(app, event)
            % hObject    handle to ch5_disp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of ch5_disp
            app = refreshfig(app);
            %guidata(hObject, app);
        end

        % Value changed function: ch5_max
        function ch5_max_Callback(app, event)
            % hObject    handle to ch5_max (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ch5_max as text
            %        str2double(get(hObject,'String')) returns contents of ch5_max as a double
            %set(app.ch5_max,'Value',str2num(get(hObject,'String')))
            app = refreshfig(app);
            %guidata(hObject, app);
        end

        % Value changed function: ch5_min
        function ch5_min_Callback(app, event)
            % hObject    handle to ch5_min (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ch5_min as text
            %        str2double(get(hObject,'String')) returns contents of ch5_min as a double
            %set(app.ch5_min,'Value',str2num(get(hObject,'String')))
            app = refreshfig(app);
            %guidata(hObject, app);
        end

        % Value changed function: pos_select
        function pos_select_Callback(app, event)
            % hObject    handle to pos_select (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of pos_select as text
            %        str2double(get(hObject,'String')) returns contents of pos_select as a double
            %set(app.pos_select,'Value',str2num(get(hObject,'String')))
            
            well = get(app.well_select,'Value');
            pos = get(app.pos_select,'Value');
                        
            if contains(pos,'-')
                fname = [well '-' pos '.mat'];
            else
                fname = [well '_' pos '.mat'];
            end
            app.fidx = find(strcmp(fname,app.fnames));
            
            app.reload = true;
            app = refreshfig(app);
            %guidata(hObject, app);
        end

        % Value changed function: well_select
        function well_select_Callback(app, event)
            % hObject    handle to well_select (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of well_select as text
            %        str2double(get(hObject,'String')) returns contents of well_select as a double
            
            well = get(app.well_select,'Value');
            pos = get(app.pos_select,'Value');
                        
            if contains(pos,'-')
                fname = [well '-' pos '.mat'];
            else
                fname = [well '_' pos '.mat'];
            end
            app.fidx = find(strcmp(fname,app.fnames));
            
            app.reload = true;
            app = refreshfig(app);
            %guidata(hObject, app);
        end

        % Value changed function: x_max
        function x_max_Callback(app, event)
            % hObject    handle to x_max (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of x_max as text
            %        str2double(get(hObject,'String')) returns contents of x_max as a double
               % set(app.x_max,'Value',str2num(get(hObject,'String')))
                app = refreshfig(app);
                %guidata(hObject, app);
        end

        % Value changed function: x_min
        function x_min_Callback(app, event)
            % hObject    handle to x_min (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of x_min as text
            %        str2double(get(hObject,'String')) returns contents of x_min as a double
                %set(app.x_min,'Value',str2num(get(hObject,'String')))
                app = refreshfig(app);
                %guidata(hObject, app);
        end

        % Value changed function: y_max
        function y_max_Callback(app, event)
            % hObject    handle to y_max (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of y_max as text
            %        str2double(get(hObject,'String')) returns contents of y_max as a double
                %set(app.y_max,'Value',str2num(get(hObject,'String')))
                app = refreshfig(app);
                %guidata(hObject, app);
        end

        % Value changed function: y_min
        function y_min_Callback(app, event)
            % hObject    handle to y_min (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of y_min as text
            %        str2double(get(hObject,'String')) returns contents of y_min as a double
                %set(app.y_min,'Value',str2num(get(hObject,'String')))
                app = refreshfig(app);
                %guidata(hObject, app);
        end

        % Value changed function: z_slider
        function z_slider_Callback(app, event)
            % hObject    handle to z_slider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            set(app.z_slider,'Value',round(get(app.z_slider,'Value'),0))
            app = refreshfig(app);
            %guidata(hObject, app);
        end

        % Button pushed function: ResetZoomButton
        function reset_zoom(app, event)
            set(app.x_min,'Value','0')
            set(app.y_min,'Value','0')
            set(app.x_max,'Value',num2str(app.X))
            set(app.y_max,'Value',num2str(app.Y))
            app = refreshfig(app);
        end

        % Button pushed function: DownButton
        function z_down(app, event)
            current_z = get(app.z_slider,'Value');
            set(app.z_slider,'Value',max(current_z - 1, min(app.p.slices)))
            app = refreshfig(app);
        end

        % Button pushed function: UpButton
        function z_up(app, event)
            current_z = get(app.z_slider,'Value');
            set(app.z_slider,'Value',min(current_z + 1, max(app.p.slices)))
            app = refreshfig(app);
        end

        % Value changed function: ZoomModeButton
        function zoom_mode(app, event)
            value = app.ZoomModeButton.Value;
            if value
                [x,y] = ginput(1);
                width = str2double(get(app.WidthEditField,'Value'));
                
                set(app.x_min,'Value',num2str(round(x - width./2)))
                set(app.x_max,'Value',num2str(round(x + width./2)))
                set(app.y_min,'Value',num2str(round(y - width./2)))
                set(app.y_max,'Value',num2str(round(y + width./2)))
                
                set(app.ZoomModeButton,'Value',false)
                
                app = refreshfig(app);
            end
        end

        % Button pushed function: SaveImagesButton
        function save_images(app, event)
            
            if isfield(app.p,'out_path')
                path = app.p.out_path;
            else
                path = app.p.path;
            end
            
            folder = uigetdir(path,'Where to save the images.');
            
            z = get(app.z_slider,'Value');
            
            if isfield(app.seg,'cells')
                cell_bdry = bwboundaries(app.seg.cells(:,:,z));
            else
                cell_bdry = {};
            end
            
            if isfield(app.seg,'arrays')
                array_bdry = bwboundaries(app.seg.arrays(:,:,z));
            else
                array_bdry = {};
            end
            
            for ch = 1:length(app.p.channels)
                figure()
                ch_im = app.current_im(ch).im(:,:,z);
            
                low = str2double(get(app.(['ch' num2str(ch) '_min']),'Value'));
                high = str2double(get(app.(['ch' num2str(ch) '_max']),'Value'));
            
                ch_im = imadjust(ch_im./high,[low./high;1],[0;1]);
                imrgb = zeros([1080,1080]);
                imrgb = imrgb + cat(3, ch_im*app.colors(ch,1),ch_im*app.colors(ch,2),...
                    ch_im*app.colors(ch,3));
                
                imshow(imrgb,'Parent',gca)
                hold on
                
                linewidth = str2double(get(app.linewidth,'Value'));
                if get(app.(['ch' num2str(ch) '_show_cellseg']),'Value')
                    
                    if get(app.(['ch' num2str(ch) '_show_arrayseg']),'Value')
                        style = '--';
                    else
                        style = '-';
                    end
                    
                    for b = 1:length(cell_bdry)
                        bdry = cell_bdry{b};
                        plot(gca,bdry(:,2), bdry(:,1), 'Color','white',...
                            'LineWidth',linewidth,'LineStyle',style);
                    end
                end
                
                if get(app.(['ch' num2str(ch) '_show_arrayseg']),'Value')

                    for b = 1:length(array_bdry)
                        bdry = array_bdry{b};
                        plot(gca,bdry(:,2), bdry(:,1), 'Color', 'white',...
                            'LineWidth',linewidth,'LineStyle','-');
                    end
                end
                
                hold off
                
                xlim(gca,[str2double(get(app.x_min,'Value')),...
                    str2double(get(app.x_max,'Value'))])
                ylim(gca,[str2double(get(app.y_min,'Value')),...
                    str2double(get(app.y_max,'Value'))])
                
                set(gcf, 'InvertHardCopy', 'off')
                saveas(gcf,[folder '/' get(app.image_name,'Value') '_ch' num2str(ch) '.png'])
            end
        end

        % Button pushed function: prev_im
        function prev_imButtonPushed(app, event)
            if app.fidx == 1
                return
            end
            
            app.fidx = app.fidx - 1;
            fname = app.fnames{app.fidx};
            [split1,~] = strsplit(fname,'_');
            [split2,~] = strsplit(split1{1},'-');
            well = split2{1};
            pos = fname(length(well)+2:end-4);
            set(app.well_select,'Value', well)
            set(app.pos_select,'Value', pos)
            
            app.reload = true;
            refreshfig(app)
        end

        % Button pushed function: next_im
        function next_imButtonPushed(app, event)
            if app.fidx == length(app.fnames)
                return
            end
            
            app.fidx = app.fidx + 1;
            fname = app.fnames{app.fidx};
            [split1,~] = strsplit(fname,'_');
            [split2,~] = strsplit(split1{1},'-');
            well = split2{1};
            pos = fname(length(well)+2:end-4);
            set(app.well_select,'Value', well)
            set(app.pos_select,'Value', pos)
            
            app.reload = true;
            refreshfig(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'stack_viewer';
            app.z_slider = uislider(app.figure1);
            app.z_slider.Limits = [1 26];
            app.z_slider.MajorTicks = [];
            app.z_slider.ValueChangedFcn = createCallbackFcn(app, @z_slider_Callback, true);
            app.z_slider.MinorTicks = [];
            app.z_slider.Value = 1;
            app.ch1_disp = uicheckbox(app.figure1);
            app.ch1_disp.ValueChangedFcn = createCallbackFcn(app, @ch1_disp_Callback, true);
            app.ch1_disp.Text = 'Channel 1';
            app.ch2_disp = uicheckbox(app.figure1);
            app.ch2_disp.ValueChangedFcn = createCallbackFcn(app, @ch2_disp_Callback, true);
            app.ch2_disp.Text = 'Channel 2';
            app.ch3_disp = uicheckbox(app.figure1);
            app.ch3_disp.ValueChangedFcn = createCallbackFcn(app, @ch3_disp_Callback, true);
            app.ch3_disp.Text = 'Channel 3';
            app.title = uilabel(app.figure1);
            app.title.Text = 'Stack Viewer';
            app.ch1_min = uieditfield(app.figure1, 'text');
            app.ch1_min.ValueChangedFcn = createCallbackFcn(app, @ch1_min_Callback, true);
            app.ch1_min.Value = '0';
            app.ch1_max = uieditfield(app.figure1, 'text');
            app.ch1_max.ValueChangedFcn = createCallbackFcn(app, @ch1_max_Callback, true);
            app.ch1_max.Value = '0';
            app.ch2_min = uieditfield(app.figure1, 'text');
            app.ch2_min.ValueChangedFcn = createCallbackFcn(app, @ch2_min_Callback, true);
            app.ch2_min.Value = '0';
            app.ch2_max = uieditfield(app.figure1, 'text');
            app.ch2_max.ValueChangedFcn = createCallbackFcn(app, @ch2_max_Callback, true);
            app.ch2_max.Value = '0';
            app.ch3_min = uieditfield(app.figure1, 'text');
            app.ch3_min.ValueChangedFcn = createCallbackFcn(app, @ch3_min_Callback, true);
            app.ch3_min.Value = '0';
            app.ch3_max = uieditfield(app.figure1, 'text');
            app.ch3_max.ValueChangedFcn = createCallbackFcn(app, @ch3_max_Callback, true);
            app.ch3_max.Value = '0';
            app.text3 = uilabel(app.figure1);
            app.text3.Text = 'Display';
            app.text4 = uilabel(app.figure1);
            app.text4.Text = 'Min';
            app.text5 = uilabel(app.figure1);
            app.text5.Text = 'Max';
            app.well_select = uieditfield(app.figure1, 'text');
            app.well_select.ValueChangedFcn = createCallbackFcn(app, @well_select_Callback, true);
            app.well_select.Value = 'A1';
            app.text6 = uilabel(app.figure1);
            app.text6.Text = 'Well';
            app.text7 = uilabel(app.figure1);
            app.text7.Text = 'Position';
            app.pos_select = uieditfield(app.figure1, 'text');
            app.pos_select.ValueChangedFcn = createCallbackFcn(app, @pos_select_Callback, true);
            app.pos_select.Value = '1';
            app.auto_contrast = uibutton(app.figure1, 'push');
            app.auto_contrast.ButtonPushedFcn = createCallbackFcn(app, @auto_contrast_Callback, true);
            app.auto_contrast.Text = 'Auto-contrast';
            app.ch4_disp = uicheckbox(app.figure1);
            app.ch4_disp.ValueChangedFcn = createCallbackFcn(app, @ch4_disp_Callback, true);
            app.ch4_disp.Text = 'Channel 4';
            app.ch4_min = uieditfield(app.figure1, 'text');
            app.ch4_min.ValueChangedFcn = createCallbackFcn(app, @ch4_min_Callback, true);
            app.ch4_min.Value = '0';
            app.ch4_max = uieditfield(app.figure1, 'text');
            app.ch4_max.ValueChangedFcn = createCallbackFcn(app, @ch4_max_Callback, true);
            app.ch4_max.Value = '0';
            app.ch5_disp = uicheckbox(app.figure1);
            app.ch5_disp.ValueChangedFcn = createCallbackFcn(app, @ch5_disp_Callback, true);
            app.ch5_disp.Text = 'Channel 5';
            app.ch5_min = uieditfield(app.figure1, 'text');
            app.ch5_min.ValueChangedFcn = createCallbackFcn(app, @ch5_min_Callback, true);
            app.ch5_min.Value = '0';
            app.ch5_max = uieditfield(app.figure1, 'text');
            app.ch5_max.ValueChangedFcn = createCallbackFcn(app, @ch5_max_Callback, true);
            app.ch5_max.Value = '0';
            app.cellseg = uicheckbox(app.figure1);
            app.cellseg.ValueChangedFcn = createCallbackFcn(app, @cellseg_Callback, true);
            app.cellseg.Text = 'cellseg';
            app.arrayseg = uicheckbox(app.figure1);
            app.arrayseg.ValueChangedFcn = createCallbackFcn(app, @arrayseg_Callback, true);
            app.arrayseg.Text = 'arrayseg';
            app.x_min = uieditfield(app.figure1, 'text');
            app.x_min.ValueChangedFcn = createCallbackFcn(app, @x_min_Callback, true);
            app.x_min.Value = 'Edit Text';
            app.x_max = uieditfield(app.figure1, 'text');
            app.x_max.ValueChangedFcn = createCallbackFcn(app, @x_max_Callback, true);
            app.x_max.Value = 'Edit Text';
            app.y_min = uieditfield(app.figure1, 'text');
            app.y_min.ValueChangedFcn = createCallbackFcn(app, @y_min_Callback, true);
            app.y_min.Value = 'Edit Text';
            app.y_max = uieditfield(app.figure1, 'text');
            app.y_max.ValueChangedFcn = createCallbackFcn(app, @y_max_Callback, true);
            app.y_max.Value = 'Edit Text';
            app.text8 = uilabel(app.figure1);
            app.text8.Text = 'X';
            app.text9 = uilabel(app.figure1);
            app.text9.Text = 'Y';
            app.text10 = uilabel(app.figure1);
            app.text10.Text = 'min';
            app.text11 = uilabel(app.figure1);
            app.text11.Text = 'max';
            app.ResetZoomButton = uibutton(app.figure1, 'push');
            app.ResetZoomButton.ButtonPushedFcn = createCallbackFcn(app, @reset_zoom, true);
            app.ResetZoomButton.Text = 'Reset Zoom';
            app.DownButton = uibutton(app.figure1, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @z_down, true);
            app.DownButton.Text = 'Down';
            app.UpButton = uibutton(app.figure1, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @z_up, true);
            app.UpButton.Text = 'Up';
            app.ZoomModeButton = uibutton(app.figure1, 'state');
            app.ZoomModeButton.ValueChangedFcn = createCallbackFcn(app, @zoom_mode, true);
            app.ZoomModeButton.Text = 'Zoom Mode';
            app.WidthEditFieldLabel = uilabel(app.figure1);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.figure1, 'text');
            app.SaveImagesButton = uibutton(app.figure1, 'push');
            app.SaveImagesButton.ButtonPushedFcn = createCallbackFcn(app, @save_images, true);
            app.SaveImagesButton.Text = 'Save Images';
            app.text4_2 = uilabel(app.figure1);
            app.text4_2.Text = 'Show Arrayseg';
            app.text4_3 = uilabel(app.figure1);
            app.text4_3.Text = 'Show Cellseg';
            app.ch1_show_arrayseg = uicheckbox(app.figure1);
            app.ch1_show_arrayseg.Text = '';
            app.ch1_show_cellseg = uicheckbox(app.figure1);
            app.ch1_show_cellseg.Text = '';
            app.ch2_show_arrayseg = uicheckbox(app.figure1);
            app.ch2_show_arrayseg.Text = '';
            app.ch2_show_cellseg = uicheckbox(app.figure1);
            app.ch2_show_cellseg.Text = '';
            app.ch3_show_arrayseg = uicheckbox(app.figure1);
            app.ch3_show_arrayseg.Text = '';
            app.ch3_show_cellseg = uicheckbox(app.figure1);
            app.ch3_show_cellseg.Text = '';
            app.ch4_show_arrayseg = uicheckbox(app.figure1);
            app.ch4_show_arrayseg.Text = '';
            app.ch4_show_cellseg = uicheckbox(app.figure1);
            app.ch4_show_cellseg.Text = '';
            app.ch5_show_arrayseg = uicheckbox(app.figure1);
            app.ch5_show_arrayseg.Text = '';
            app.ch5_show_cellseg = uicheckbox(app.figure1);
            app.ch5_show_cellseg.Text = '';
            app.ImageNameEditFieldLabel = uilabel(app.figure1);
            app.ImageNameEditFieldLabel.Text = 'Image Name';
            app.image_name = uieditfield(app.figure1, 'text');
            app.LineWidthEditFieldLabel = uilabel(app.figure1);
            app.LineWidthEditFieldLabel.Text = 'Line Width';
            app.linewidth = uieditfield(app.figure1, 'text');
            app.prev_im = uibutton(app.figure1, 'push');
            app.prev_im.ButtonPushedFcn = createCallbackFcn(app, @prev_imButtonPushed, true);
            app.prev_im.Text = '<';
            app.next_im = uibutton(app.figure1, 'push');
            app.next_im.ButtonPushedFcn = createCallbackFcn(app, @next_imButtonPushed, true);
            app.next_im.Text = '>';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stack_viewer_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @stack_viewer_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end